classdef Projekt1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UploadcustomimageButton        matlab.ui.control.Button
        OutputEditFieldLabel           matlab.ui.control.Label
        OutputEditField                matlab.ui.control.EditField
        PredefinedimagesDropDownLabel  matlab.ui.control.Label
        ImageAxes                      matlab.ui.control.UIAxes
        PredefinedimagesDropDown       matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            try
                im = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end   
            % Display the truecolor image
            imagesc(app.ImageAxes,im);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadcustomimageButton
        function UploadImage(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UploadcustomimageButton = uibutton(app.UIFigure, 'push');
            app.UploadcustomimageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImage, true);
            app.UploadcustomimageButton.Text = 'Upload custom image';
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'text');
            app.OutputEditField.Editable = 'off';
            app.PredefinedimagesDropDownLabel = uilabel(app.UIFigure);
            app.PredefinedimagesDropDownLabel.Text = 'Predefined images';
            app.PredefinedimagesDropDown = uidropdown(app.UIFigure);
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, '')
            xlabel(app.ImageAxes, '')
            ylabel(app.ImageAxes, '')
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Projekt1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end